classdef ProyectoIntegrador2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ts5                           matlab.ui.control.EditField
        Testablecimiento5EditFieldLabel  matlab.ui.control.Label
        ts2                           matlab.ui.control.EditField
        Testablecimiento2EditFieldLabel  matlab.ui.control.Label
        tr                            matlab.ui.control.EditField
        TiempodesubidaEditFieldLabel  matlab.ui.control.Label
        tp                            matlab.ui.control.EditField
        TiempopicoEditFieldLabel      matlab.ui.control.Label
        mp                            matlab.ui.control.EditField
        SobreelongacinEditFieldLabel  matlab.ui.control.Label
        zetha                         matlab.ui.control.EditField
        zethaEditFieldLabel           matlab.ui.control.Label
        CalcularButton_2              matlab.ui.control.Button
        TFReducida                    matlab.ui.control.Label
        ReducirButton                 matlab.ui.control.Button
        GraficarButton                matlab.ui.control.Button
        CheckBox_1                    matlab.ui.control.CheckBox
        SimularButton                 matlab.ui.control.Button
        ConsignaDropDown              matlab.ui.control.DropDown
        ConsignaDropDownLabel         matlab.ui.control.Label
        Controlador                   matlab.ui.control.EditField
        Matriz                        matlab.ui.control.Label
        VariablesdeestadoButtonGroup  matlab.ui.container.ButtonGroup
        MatrizDButton                 matlab.ui.control.RadioButton
        MatrizCButton                 matlab.ui.control.RadioButton
        MatrizBButton                 matlab.ui.control.RadioButton
        MatrizAButton                 matlab.ui.control.RadioButton
        Salida_funcion                matlab.ui.control.Label
        Sensor_funcion                matlab.ui.control.Label
        Planta_funcion                matlab.ui.control.Label
        Sensor_num                    matlab.ui.control.EditField
        Sensor_dem                    matlab.ui.control.EditField
        Planta_dem                    matlab.ui.control.EditField
        Planta_num                    matlab.ui.control.EditField
        Actuador_funcion              matlab.ui.control.Label
        Actuador_dem                  matlab.ui.control.EditField
        Actuador_num                  matlab.ui.control.EditField
        CalcularButton                matlab.ui.control.Button
        FuncindeTransferenciaLabel    matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        SistemadecontrolbalanceodeunavinLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        FuncindeTransferenciaoriginalTab  matlab.ui.container.Tab
        Grafica1                      matlab.ui.control.UIAxes
        FuncindeTransferenciareducidaTab  matlab.ui.container.Tab
        Grafica2                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_system('Proyecto_Integrador2');
            find_system;

            set_param('Proyecto_Integrador2', 'SimulationCommand', 'Start');
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %%Actuador
            Actuador_numerador = str2num(app.Actuador_num.Value);
            Actuador_denominador = str2num(app.Actuador_dem.Value);
            assignin('base','Actuador_num',Actuador_numerador)
            assignin('base','Actuador_dem',Actuador_denominador)
            
            
            Actuador = tf(Actuador_numerador,Actuador_denominador);
            app.Actuador_funcion.Text = evalc('Actuador');


            %%Planta
            Planta_numerador = str2num(app.Planta_num.Value);
            Planta_denominador = str2num(app.Planta_dem.Value);
            assignin('base','Planta_num',Planta_numerador)
            assignin('base','Planta_dem',Planta_denominador)
            
            Planta = tf(Planta_numerador,Planta_denominador);
            app.Planta_funcion.Text = evalc('Planta');


            %%Sensor
            Sensor_numerador = str2num(app.Sensor_num.Value);
            Sensor_denominador = str2num(app.Sensor_dem.Value);
            assignin('base','Sensor_num',Sensor_numerador)
            assignin('base','Sensor_dem',Sensor_denominador)
            
            Sensor = tf(Sensor_numerador,Sensor_denominador);
            app.Sensor_funcion.Text = evalc('Sensor');

            
            %%Controlador
            Const_Controlador = str2num(app.Controlador.Value);
            assignin('base','Controlador1',Const_Controlador)


            %%Salida
            global G Salida
            G = Actuador*Planta*Const_Controlador;
            Salida = G/(1+(G*Sensor));
            app.Salida_funcion.Text = evalc('Salida');

            global A B C D simout
            [var1,var2] = tfdata(Salida);
            var11 = cell2mat(var1);
            var22 = cell2mat(var2);
            [A,B,C,D] = tf2ss(var11,var22)

            
            %simout = sim("Proyecto_Integrador2.slx");
        end

        % Selection changed function: VariablesdeestadoButtonGroup
        function VariablesdeestadoButtonGroupSelectionChanged(app, event)
            selectedButton = app.VariablesdeestadoButtonGroup.SelectedObject;
            global A B C D
            if selectedButton == app.MatrizAButton
                app.Matriz.Text = evalc('A');
            elseif selectedButton == app.MatrizBButton
                app.Matriz.Text = evalc('B');
            elseif selectedButton == app.MatrizCButton
                app.Matriz.Text = evalc('C');
            elseif selectedButton == app.MatrizDButton
                app.Matriz.Text = evalc('D');
            end

        end

        % Value changed function: ConsignaDropDown
        function ConsignaDropDownValueChanged(app, event)
            valueConsigna = app.ConsignaDropDown.Value;

            if valueConsigna == "Impulso"
                assignin('base','Entrada',1)

            elseif valueConsigna == "Escalón"
                assignin('base','Entrada',2)

            elseif valueConsigna == "Rampa"
                assignin('base','Entrada',3)

            elseif valueConsigna == "Parábola"
                assignin('base','Entrada',4)
                
            end

        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            global simout
            simout = sim("Proyecto_Integrador2.slx");
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            valueConsigna = app.ConsignaDropDown.Value;
            valueCheck = app.CheckBox_1.Value;

            global G_red simout Salida

            G_red = balred(Salida,2)
            
            plot(app.Grafica1,simout.Original.Time,simout.Original.Data,'r');
            hold(app.Grafica1,"on")
            plot(app.Grafica1,simout.Respuesta.Time,simout.Respuesta.Data,'b');
            hold(app.Grafica1,"off")

            
            if valueCheck == 1

                %plot(app.Grafica1,simout.Respuesta.Time,simout.Respuesta.Data,'b');
                hold(app.Grafica1,"on")

                t = 0:0.01:50;
                if valueConsigna == "Impulso"
                    y = impulse(G_red,50);
                    y12 = y(:,1,1);
                elseif valueConsigna == "Escalón"
                    %DatosY = step(G_red,10);
                    y = step(G_red,t);
                    y12 = y(:,1,1);
                    
                elseif valueConsigna == "Rampa"
                    r1 = t;
                    y = lsim(G_red,r1,t);
                    y12 = y(:,1,1);

                elseif valueConsigna == "Parábola"
                    r2 = ((t.^2)/2);
                    y = lsim(G_red,r2,t);
                    y12 = y(:,1,1);

                end
                
                app.Grafica2.XLim = [0 50];
                plot(app.Grafica2,t,y12,'b');
                
                hold(app.Grafica2,"on")

            else
                %plot(app.Grafica1,simout.Respuesta.Time,simout.Respuesta.Data,'b');
                hold(app.Grafica1,"off")

                t = 0:0.01:50;
                if valueConsigna == "Impulso"
                    y = impulse(G_red,t);
                    y12 = y(:,1,1);

                elseif valueConsigna == "Escalón"
                    %DatosY = step(G_red,50);
                    y = step(G_red,t);
                    y12 = y(:,1,1);
    
                elseif valueConsigna == "Rampa"
                    r1 = t;
                    y = lsim(G_red,r1,t);
                    y12 = y(:,1,1);

                elseif valueConsigna == "Parábola"
                    r2 = ((t.^2)/2);
                    y = lsim(G_red,r2,t);
                    y12 = y(:,1,1);
                    
                end

                app.Grafica2.XLim = [0 50];
                plot(app.Grafica2,t,y12,'b');
                
                hold(app.Grafica2,"off")

            end


        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
        end

        % Button pushed function: ReducirButton
        function ReducirButtonPushed(app, event)
            global G Salida G_red
            
            G_red = balred(Salida,2)

            app.TFReducida.Text = evalc('G_red');
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            global Salida
            
            [var,t] = step(Salida);
            [peak,F]= max(var); 
            valult= var(end);
            tp= t(F); 
            Mp= (peak-valult)./(valult); 
            ln_mp= log(Mp);
            zetha= sqrt(((ln_mp)^2)./((pi^2)+(ln_mp)^2)); 
            wd= (pi)./(tp);
            wn= (wd)./sqrt(1-zetha^2);
            ts2= 4./(zetha*wn); 
            ts5= 3./(zetha*wn); 
            varpor= 0.95*valult;
            rest= var-varpor;
            [mini,I]= min(abs(rest));
            tr= t(I); 

            app.zetha.Value = evalc("zetha");
            app.tp.Value = evalc("tr");
            app.tr.Value = evalc("tp");
            app.mp.Value = evalc("Mp");
            app.ts2.Value = evalc("ts2");
            app.ts5.Value = evalc("ts5");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FuncindeTransferenciaoriginalTab = uitab(app.TabGroup);
            app.FuncindeTransferenciaoriginalTab.Title = 'Función de Transferencia original';
            app.Grafica1 = uiaxes(app.FuncindeTransferenciaoriginalTab);
            title(app.Grafica1, 'Respuesta')
            xlabel(app.Grafica1, 'Tiempo')
            ylabel(app.Grafica1, 'Amplitud')
            zlabel(app.Grafica1, 'Z')
            app.FuncindeTransferenciareducidaTab = uitab(app.TabGroup);
            app.FuncindeTransferenciareducidaTab.Title = 'Función de Transferencia reducida';
            app.Grafica2 = uiaxes(app.FuncindeTransferenciareducidaTab);
            title(app.Grafica2, 'Respuesta')
            xlabel(app.Grafica2, 'Tiempo')
            ylabel(app.Grafica2, 'Amplitud')
            zlabel(app.Grafica2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'avion.png');
            app.SistemadecontrolbalanceodeunavinLabel = uilabel(app.UIFigure);
            app.SistemadecontrolbalanceodeunavinLabel.Text = 'Sistema de control balanceo de un avión';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ControlBloques.png');
            app.FuncindeTransferenciaLabel = uilabel(app.UIFigure);
            app.FuncindeTransferenciaLabel.Text = 'Función de Transferencia:';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.Actuador_num = uieditfield(app.UIFigure, 'text');
            app.Actuador_dem = uieditfield(app.UIFigure, 'text');
            app.Actuador_funcion = uilabel(app.UIFigure);
            app.Actuador_funcion.Text = 'Actuador';
            app.Planta_num = uieditfield(app.UIFigure, 'text');
            app.Planta_dem = uieditfield(app.UIFigure, 'text');
            app.Sensor_dem = uieditfield(app.UIFigure, 'text');
            app.Sensor_num = uieditfield(app.UIFigure, 'text');
            app.Planta_funcion = uilabel(app.UIFigure);
            app.Planta_funcion.Text = 'Planta';
            app.Sensor_funcion = uilabel(app.UIFigure);
            app.Sensor_funcion.Text = 'Sensor';
            app.Salida_funcion = uilabel(app.UIFigure);
            app.Salida_funcion.Text = '';
            app.VariablesdeestadoButtonGroup = uibuttongroup(app.UIFigure);
            app.VariablesdeestadoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VariablesdeestadoButtonGroupSelectionChanged, true);
            app.VariablesdeestadoButtonGroup.Title = 'Variables de estado';
            app.MatrizAButton = uiradiobutton(app.VariablesdeestadoButtonGroup);
            app.MatrizAButton.Text = 'Matriz A';
            app.MatrizAButton.Value = true;
            app.MatrizBButton = uiradiobutton(app.VariablesdeestadoButtonGroup);
            app.MatrizBButton.Text = 'Matriz B';
            app.MatrizCButton = uiradiobutton(app.VariablesdeestadoButtonGroup);
            app.MatrizCButton.Text = 'Matriz C';
            app.MatrizDButton = uiradiobutton(app.VariablesdeestadoButtonGroup);
            app.MatrizDButton.Text = 'Matriz D';
            app.Matriz = uilabel(app.UIFigure);
            app.Matriz.Text = '';
            app.Controlador = uieditfield(app.UIFigure, 'text');
            app.ConsignaDropDownLabel = uilabel(app.UIFigure);
            app.ConsignaDropDownLabel.Text = 'Consigna';
            app.ConsignaDropDown = uidropdown(app.UIFigure);
            app.ConsignaDropDown.Items = {'Impulso', 'Escalón', 'Rampa', 'Parábola'};
            app.ConsignaDropDown.ValueChangedFcn = createCallbackFcn(app, @ConsignaDropDownValueChanged, true);
            app.ConsignaDropDown.Value = 'Impulso';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = 'Múltiples Respuestas';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.ReducirButton = uibutton(app.UIFigure, 'push');
            app.ReducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReducirButtonPushed, true);
            app.ReducirButton.Text = 'Reducir';
            app.TFReducida = uilabel(app.UIFigure);
            app.TFReducida.Text = '';
            app.CalcularButton_2 = uibutton(app.UIFigure, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.zethaEditFieldLabel = uilabel(app.UIFigure);
            app.zethaEditFieldLabel.Text = 'zetha';
            app.zetha = uieditfield(app.UIFigure, 'text');
            app.SobreelongacinEditFieldLabel = uilabel(app.UIFigure);
            app.SobreelongacinEditFieldLabel.Text = 'Sobreelongación';
            app.mp = uieditfield(app.UIFigure, 'text');
            app.TiempopicoEditFieldLabel = uilabel(app.UIFigure);
            app.TiempopicoEditFieldLabel.Text = 'Tiempo pico';
            app.tp = uieditfield(app.UIFigure, 'text');
            app.TiempodesubidaEditFieldLabel = uilabel(app.UIFigure);
            app.TiempodesubidaEditFieldLabel.Text = 'Tiempo de subida';
            app.tr = uieditfield(app.UIFigure, 'text');
            app.Testablecimiento2EditFieldLabel = uilabel(app.UIFigure);
            app.Testablecimiento2EditFieldLabel.Text = 'T establecimiento (2%)';
            app.ts2 = uieditfield(app.UIFigure, 'text');
            app.Testablecimiento5EditFieldLabel = uilabel(app.UIFigure);
            app.Testablecimiento5EditFieldLabel.Text = 'T establecimiento (5%)';
            app.ts5 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoIntegrador2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end