classdef ProyectoIntegrador3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DLabel                        matlab.ui.control.Label
        ILabel                        matlab.ui.control.Label
        KLabel                        matlab.ui.control.Label
        PruebaButton                  matlab.ui.control.Button
        Controlador_3                 matlab.ui.control.EditField
        Controlador_2                 matlab.ui.control.EditField
        ControladorDropDown           matlab.ui.control.DropDown
        ControladorDropDownLabel      matlab.ui.control.Label
        GraficarButton                matlab.ui.control.Button
        CheckBox_1                    matlab.ui.control.CheckBox
        SimularButton                 matlab.ui.control.Button
        ConsignaDropDown              matlab.ui.control.DropDown
        ConsignaDropDownLabel         matlab.ui.control.Label
        Controlador                   matlab.ui.control.EditField
        Sensor_num                    matlab.ui.control.EditField
        Sensor_dem                    matlab.ui.control.EditField
        Planta_dem                    matlab.ui.control.EditField
        Planta_num                    matlab.ui.control.EditField
        Actuador_dem                  matlab.ui.control.EditField
        Actuador_num                  matlab.ui.control.EditField
        SensorLabel                   matlab.ui.control.Label
        PlantaLabel                   matlab.ui.control.Label
        PerturbacionesLabel           matlab.ui.control.Label
        ActuadorLabel                 matlab.ui.control.Label
        ControladorLabel              matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        ControlalabeodeunavinLabel    matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        FuncindeTransferenciaoriginalTab  matlab.ui.container.Tab
        Grafica1                      matlab.ui.control.UIAxes
        FuncindeTransferenciareducidaTab  matlab.ui.container.Tab
        Grafica2                      matlab.ui.control.UIAxes
        TabGroup2                     matlab.ui.container.TabGroup
        FunciondetransferenciaTab     matlab.ui.container.Tab
        CalcularButton                matlab.ui.control.Button
        Sensor_funcion                matlab.ui.control.Label
        Actuador_funcion              matlab.ui.control.Label
        Planta_funcion                matlab.ui.control.Label
        Matriz                        matlab.ui.control.Label
        Salida_funcion                matlab.ui.control.Label
        FuncindeTransferenciaLabel    matlab.ui.control.Label
        VariablesdeestadoButtonGroup  matlab.ui.container.ButtonGroup
        MatrizDButton                 matlab.ui.control.RadioButton
        MatrizCButton                 matlab.ui.control.RadioButton
        MatrizBButton                 matlab.ui.control.RadioButton
        MatrizAButton                 matlab.ui.control.RadioButton
        FuncionreducidayparmetrosTab  matlab.ui.container.Tab
        ts5                           matlab.ui.control.EditField
        Testablecimiento5EditFieldLabel  matlab.ui.control.Label
        ts2                           matlab.ui.control.EditField
        Testablecimiento2EditFieldLabel  matlab.ui.control.Label
        tr                            matlab.ui.control.EditField
        TiempodesubidaEditFieldLabel  matlab.ui.control.Label
        tp                            matlab.ui.control.EditField
        TiempopicoEditFieldLabel      matlab.ui.control.Label
        mp                            matlab.ui.control.EditField
        SobreelongacinEditFieldLabel  matlab.ui.control.Label
        zetha                         matlab.ui.control.EditField
        zethaEditFieldLabel           matlab.ui.control.Label
        e_parabola                    matlab.ui.control.EditField
        ErrordeaceleracionLabel       matlab.ui.control.Label
        e_rampa                       matlab.ui.control.EditField
        ErrordevelocidadLabel         matlab.ui.control.Label
        e_escalon                     matlab.ui.control.EditField
        ErrordeposicionLabel          matlab.ui.control.Label
        CalcularButton_2              matlab.ui.control.Button
        ReducirButton                 matlab.ui.control.Button
        TFReducida                    matlab.ui.control.Label
        EstabilidadTab                matlab.ui.container.Tab
        ValoresK                      matlab.ui.control.Label
        xmaxEditField                 matlab.ui.control.NumericEditField
        LmiteejexmaxEditFieldLabel    matlab.ui.control.Label
        xminEditField                 matlab.ui.control.NumericEditField
        LmiteejexminEditFieldLabel    matlab.ui.control.Label
        LimpiarButton                 matlab.ui.control.Button
        CalcularButton_3              matlab.ui.control.Button
        RaicesGrafica                 matlab.ui.control.UIAxes
        Region                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_system('Proyecto_Integrador3');
            find_system;

            set_param('Proyecto_Integrador3', 'SimulationCommand', 'Start');
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %%Actuador
            Actuador_numerador = str2num(app.Actuador_num.Value);
            Actuador_denominador = str2num(app.Actuador_dem.Value);
            assignin('base','Actuador_num',Actuador_numerador)
            assignin('base','Actuador_dem',Actuador_denominador)
            
            
            Actuador = tf(Actuador_numerador,Actuador_denominador);
            app.Actuador_funcion.Text = evalc('Actuador');


            %%Planta
            Planta_numerador = str2num(app.Planta_num.Value);
            Planta_denominador = str2num(app.Planta_dem.Value);
            assignin('base','Planta_num',Planta_numerador)
            assignin('base','Planta_dem',Planta_denominador)
            
            Planta = tf(Planta_numerador,Planta_denominador);
            app.Planta_funcion.Text = evalc('Planta');


            %%Sensor
            Sensor_numerador = str2num(app.Sensor_num.Value);
            Sensor_denominador = str2num(app.Sensor_dem.Value);
            assignin('base','Sensor_num',Sensor_numerador)
            assignin('base','Sensor_dem',Sensor_denominador)
            
            Sensor = tf(Sensor_numerador,Sensor_denominador);
            app.Sensor_funcion.Text = evalc('Sensor');

            
            %%Controladores
            valueControlador = app.ControladorDropDown.Value;

            %%Controlador proporcional para evitar error al iniciar
            Const_Controlador = str2num(app.Controlador.Value);
            assignin('base','Controlador1',Const_Controlador)
            assignin('base','Controlador2',0)
            assignin('base','Controlador3',0)

            if valueControlador == "Proporcional"
                
                %%Controlador proporcional
                Const_Controlador = str2num(app.Controlador.Value);
                assignin('base','Controlador1',Const_Controlador)
                assignin('base','Controlador2',0)
                assignin('base','Controlador3',0)
    
            elseif valueControlador == "Integral"
                
                %%Controlador proporcional integral
                Const_Controlador = str2num(app.Controlador.Value);
                assignin('base','Controlador1',Const_Controlador)
                Const_Controlador2 = str2num(app.Controlador_2.Value);
                assignin('base','Controlador2',Const_Controlador2)
                assignin('base','Controlador3',0)

            elseif valueControlador == "Derivativo"
                
                %%Controlador proporcional derivativo
                Const_Controlador = str2num(app.Controlador.Value);
                assignin('base','Controlador1',Const_Controlador)
                Const_Controlador3 = str2num(app.Controlador_3.Value);
                assignin('base','Controlador3',Const_Controlador3)
                assignin('base','Controlador2',0)

            end


            %%Salida
            global G G2 Salida Salida2

            %{ %}
            if valueControlador == "Proporcional"

                %%Controlador proporcional
                G = Actuador*Planta*Const_Controlador;
                Salida = G/(1+(G*Sensor));
                app.Salida_funcion.Text = evalc('Salida');

            elseif valueControlador == "Integral"
                
                %%Controlador proporcional integral
                Cntrl_Integral = tf(1, [Const_Controlador2 0])
                G = Actuador*Planta*Const_Controlador*(1+Cntrl_Integral);
                Salida = G/(1+(G*Sensor));
                app.Salida_funcion.Text = evalc('Salida');

            elseif valueControlador == "Derivativo"
                
                %%Controlador proporcional derivativo
                Cntrl_Derivativo = tf([Const_Controlador3 0], 1)
                G = Actuador*Planta*Const_Controlador*(1+Cntrl_Derivativo);
                Salida = G/(1+(G*Sensor));
                app.Salida_funcion.Text = evalc('Salida');

            end
            
            G2 = Actuador*Planta;
            Salida2 = G2/(1+(G2*Sensor));


            %%Variables de estado
            global A B C D simout
            [var1,var2] = tfdata(Salida);
            var11 = cell2mat(var1);
            var22 = cell2mat(var2);
            [A,B,C,D] = tf2ss(var11,var22)

            
            simout = sim("Proyecto_Integrador3.slx");


            %%Aqui empieza la prueba de estabilidad

            s = sym("s");
            syms K Tie Dv
            %syms Tie 
            %syms Dv
            sysp = Planta*Actuador;

            if valueControlador == "Integral"
                sysc = tf([0 1],[1 0]);
                [Nump,Denp] = tfdata(sysp);
                [Nums,Dens] = tfdata(Sensor);
                [Numc,Denc] = tfdata(sysc);

                nump = poly2sym(cell2mat(Nump),s); %Numerador planta
                denp = poly2sym(cell2mat(Denp),s); %Denominador planta
                fp = nump/denp; %Polinomio planta

                nums = poly2sym(cell2mat(Nums),s); %Numerador sensor
                dens = poly2sym(cell2mat(Dens),s); %Denominador sensor
                fs = nums/dens; %Polinomio sensor

                numc = K*poly2sym(cell2mat(Numc),s); %Numerador controlador
                denc = Tie*poly2sym(cell2mat(Denc),s); %Denominador controlador
                fc_par = numc/denc; %Polinomio controlador
                fc = fs*K + fc_par

                fg = fc*fp

                R1 = RouthHurwitzSym(fg)
                l = size(R1,1);
                s1 = sym([1,1]);
                for i=1:1
                    y1=R1(i,1);
                    s1(1,i)=y1;
                end

                ss = solve(s1>0,[K Tie],"ReturnConditions",true,'IgnoreAnalyticConstraints',true,'Real',true)

                xn = 0:0.1:500;
                yn = 0:0.01:25;

                [xg,yg]=meshgrid(xn,yn);

                if size(R1,1) == 1
                    condpar1 = sym2str(R1(1,1));
                    condpar = append(condpar1,'>0');

                elseif size(R1,1) == 2
                    condpar1 = sym2str(R1(1,1));
                    condpar2 = sym2str(R1(2,1));
                    condpar = append(condpar1, '>0 & ',condpar2,'>0');

                elseif size(R1,1) == 3
                    condpar1 = sym2str(R1(1,1));
                    condpar2 = sym2str(R1(2,1));
                    condpar3 = sym2str(R1(3,1));
                    condpar = append(condpar1, '>0 & ',condpar2,'>0 & ',condpar3,'>0');

                elseif size(R1,1) == 4
                    condpar1 = sym2str(R1(1,1));
                    condpar2 = sym2str(R1(2,1));
                    condpar3 = sym2str(R1(3,1));
                    condpar4 = sym2str(R1(4,1));
                    condpar = append(condpar1, '>0 & ',condpar2,'>0 & ',condpar3,'>0 & ',condpar4,'>0');

                end

                %condpar = sym2str(ss.conditions);
                param1 = sym2str(ss.parameters(1));
                param2 = sym2str(ss.parameters(2));
                pre = append('@(',param1,',',param2,')'); %pre = '@(x,y)';
                cond = append(pre,' ',condpar);
                fun = str2func(cond);
                rr = double(fun(xg,yg));
                tt = rr;
                qq = rr;
                tt(tt == 0) = NaN;
                qq(qq == 1) = NaN;
                surf(app.Region,xg,yg,tt,'EdgeColor','none')
                hold(app.Region,'on')
                surf(app.Region,xg,yg,qq,'EdgeColor','none')
                view(app.Region,0,90) %change to top view
                xlabel(app.Region,'K')
                ylabel(app.Region,'I')
                legend(app.Region, 'Stability Region', 'Instability Region')
            end

            if valueControlador == "Derivativo"
                
                sysc = tf([1 0],[1]);
                [Nump,Denp] = tfdata(sysp);
                [Nums,Dens] = tfdata(Sensor);
                [Numc,Denc] = tfdata(sysc);

                nump = poly2sym(cell2mat(Nump),s); %Numerador planta
                denp = poly2sym(cell2mat(Denp),s); %Denominador planta
                fp = nump/denp; %Polinomio planta

                nums = poly2sym(cell2mat(Nums),s); %Numerador sensor
                dens = poly2sym(cell2mat(Dens),s); %Denominador sensor
                fs = nums/dens; %Polinomio sensor

                numc = Dv*poly2sym(cell2mat(Numc),s); %Numerador controlador
                denc = poly2sym(cell2mat(Denc),s); %Denominador controlador
                fc_par = numc/denc; %Polinomio controlador
                fc = K*(fs + fc_par)

                fg = fc*fp

                R1 = RouthHurwitzSym(fg)
                l = size(R1,1);
                s1 = sym([1,1]);
                for i=1:1
                    y1=R1(i,1);
                    s1(1,i)=y1;
                end

                ss = solve(s1>0,[K Dv],"ReturnConditions",true,'IgnoreAnalyticConstraints',true,'Real',true)

                xn = 0:0.1:500;
                yn = 0:0.01:25;

                [xg,yg]=meshgrid(xn,yn);

                if size(R1,1) == 1
                    condpar1 = sym2str(R1(1,1));
                    condpar = append(condpar1,'>0');

                elseif size(R1,1) == 2
                    condpar1 = sym2str(R1(1,1));
                    condpar2 = sym2str(R1(2,1));
                    condpar = append(condpar1, '>0 & ',condpar2,'>0');

                elseif size(R1,1) == 3
                    condpar1 = sym2str(R1(1,1));
                    condpar2 = sym2str(R1(2,1));
                    condpar3 = sym2str(R1(3,1));
                    condpar = append(condpar1, '>0 & ',condpar2,'>0 & ',condpar3,'>0');

                elseif size(R1,1) == 4
                    condpar1 = sym2str(R1(1,1));
                    condpar2 = sym2str(R1(2,1));
                    condpar3 = sym2str(R1(3,1));
                    condpar4 = sym2str(R1(4,1));
                    condpar = append(condpar1, '>0 & ',condpar2,'>0 & ',condpar3,'>0 & ',condpar4,'>0');

                end

                %condpar = sym2str(ss.conditions);
                param1 = sym2str(ss.parameters(1));
                param2 = sym2str(ss.parameters(2));
                %pre = append('@(',param1,',',param2,')'); %pre = '@(x,y)';
                pre = '@(K,Dv)';
                cond = append(pre,' ',condpar);
                fun = str2func(cond);
                rr = double(fun(xg,yg));
                tt = rr;
                qq = rr;
                tt(tt == 0) = NaN;
                qq(qq == 1) = NaN;
                surf(app.Region,xg,yg,tt,'EdgeColor','none')
                hold(app.Region,'on')
                surf(app.Region,xg,yg,qq,'EdgeColor','none')
                view(app.Region,0,90) %change to top view
                xlabel(app.Region,'K')
                ylabel(app.Region,'D')
                legend(app.Region, 'Stability Region', 'Instability Region')
            end
            
        end

        % Selection changed function: VariablesdeestadoButtonGroup
        function VariablesdeestadoButtonGroupSelectionChanged(app, event)
            selectedButton = app.VariablesdeestadoButtonGroup.SelectedObject;
            global A B C D

            if selectedButton == app.MatrizAButton
                app.Matriz.Text = evalc('A');
            elseif selectedButton == app.MatrizBButton
                app.Matriz.Text = evalc('B');
            elseif selectedButton == app.MatrizCButton
                app.Matriz.Text = evalc('C');
            elseif selectedButton == app.MatrizDButton
                app.Matriz.Text = evalc('D');
            end

        end

        % Value changed function: ConsignaDropDown
        function ConsignaDropDownValueChanged(app, event)
            valueConsigna = app.ConsignaDropDown.Value;

            if valueConsigna == "Impulso"
                assignin('base','Entrada',1)

            elseif valueConsigna == "Escalón"
                assignin('base','Entrada',2)

            elseif valueConsigna == "Rampa"
                assignin('base','Entrada',3)

            elseif valueConsigna == "Parábola"
                assignin('base','Entrada',4)
                
            end

        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            global simout
            simout = sim("Proyecto_Integrador3.slx");
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            valueConsigna = app.ConsignaDropDown.Value;
            valueCheck = app.CheckBox_1.Value;

            global G_red simout Salida

            G_red = balred(Salida,2)
            
            plot(app.Grafica1,simout.Original.Time,simout.Original.Data,'r');
            hold(app.Grafica1,"on")
            plot(app.Grafica1,simout.Respuesta.Time,simout.Respuesta.Data,'b');
            hold(app.Grafica1,"off")

            
            if valueCheck == 1

                %plot(app.Grafica1,simout.Respuesta.Time,simout.Respuesta.Data,'b');
                hold(app.Grafica1,"on")
                t_final = 120;
                t = 0:0.001:120;

                if valueConsigna == "Impulso"
                    r = t==0;

                    [y,tOut] = impulse(G_red,t);
                    f = figure;
                    impulse(G_red,t)
                    st = findobj(f, 'Type', 'Axes')
                    copyobj(st.Children, app.Grafica2);
                    delete(f);

                    %y = impulse(G_red,50);
                    %y12 = y(:,1,1);

                elseif valueConsigna == "Escalón"
                    %DatosY = step(G_red,10);

                    [y,tOut] = step(G_red,t_final);
                    f = figure;
                    step(G_red,t_final)
                    st = findobj(f, 'Type', 'Axes')
                    copyobj(st.Children, app.Grafica2);
                    delete(f);
                    
                    r = heaviside(t);
                    %y = step(G_red,t);
                    y12 = y(:,1,1);
                    
                elseif valueConsigna == "Rampa"
                    r = t;

                    [y,tOut] = lsim(G_red,r,t);
                    f = figure;
                    lsim(G_red,r,t);
                    st = findobj(f, 'Type', 'Axes')
                    copyobj(st.Children, app.Grafica2);
                    delete(f);

                    %y = lsim(G_red,r,t);
                    %y12 = y(:,1,1);

                elseif valueConsigna == "Parábola"
                    r = ((t.^2)/2);

                    [y,tOut] = lsim(G_red,r,t);
                    f = figure;
                    lsim(G_red,r,t);
                    st = findobj(f, 'Type', 'Axes')
                    copyobj(st.Children, app.Grafica2);
                    delete(f);

                    %y = lsim(G_red,r,t);
                    %y12 = y(:,1,1);

                end
                
                app.Grafica2.XLim = [0 120];
                plot(app.Grafica2,t,r,'r');
                hold(app.Grafica2,"on")
                %plot(app.Grafica2,t,y12,'b');


            elseif valueCheck == 0
                cla(app.Grafica2)
                %plot(app.Grafica1,simout.Respuesta.Time,simout.Respuesta.Data,'b');
                hold(app.Grafica1,"off")
                t_final = 120;
                t = 0:0.001:120;

                if valueConsigna == "Impulso"
                    r = t==0;

                    [y,tOut] = impulse(G_red,t);
                    f = figure;
                    impulse(G_red,t)
                    st = findobj(f, 'Type', 'Axes')
                    copyobj(st.Children, app.Grafica2);
                    delete(f);

                    %y = impulse(G_red,t);
                    %y12 = y(:,1,1);

                elseif valueConsigna == "Escalón"
                    %DatosY = step(G_red,50);

                    [y,tOut] = step(G_red,t_final);
                    f = figure;
                    step(G_red,t_final)
                    st = findobj(f, 'Type', 'Axes')
                    copyobj(st.Children, app.Grafica2);
                    delete(f);

                    r = heaviside(t);
                    %y = step(G_red,t);
                    %y12 = y(:,1,1);
    
                elseif valueConsigna == "Rampa"
                    r = t;

                    [y,tOut] = lsim(G_red,r,t);
                    f = figure;
                    lsim(G_red,r,t);
                    st = findobj(f, 'Type', 'Axes')
                    copyobj(st.Children, app.Grafica2);
                    delete(f);

                    %y = lsim(G_red,r,t);
                    %y12 = y(:,1,1);

                elseif valueConsigna == "Parábola"
                    r = ((t.^2)/2);

                    [y,tOut] = lsim(G_red,r,t);
                    f = figure;
                    lsim(G_red,r,t);
                    st = findobj(f, 'Type', 'Axes')
                    copyobj(st.Children, app.Grafica2);
                    delete(f);

                    %y = lsim(G_red,r,t);
                    %y12 = y(:,1,1);
                    
                end

                app.Grafica2.XLim = [0 120];
                hold(app.Grafica2,"on")
                plot(app.Grafica2,t,r,'r');
                %hold(app.Grafica2,"on")
                %plot(app.Grafica2,t,y12,'b');
                
                hold(app.Grafica2,"off")

            end
 
        end

        % Button pushed function: ReducirButton
        function ReducirButtonPushed(app, event)
            global G Salida G_red Salida2 G_red2
            
            G_red = balred(Salida,2);
            G_red2 = balred(Salida2,2);

            app.TFReducida.Text = evalc('G_red');
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            global Salida G_red

            %%valueConsigna = app.ConsignaDropDown.Value;
            
            [var,t] = step(Salida);
            [peak,F]= max(var); 
            vfinal= var(end);
            tp= t(F); 
            Mp= (peak-vfinal)./(vfinal); 
            ln_mp= log(Mp);
            zetha= sqrt(((ln_mp)^2)./((pi^2)+(ln_mp)^2)); 
            wd= (pi)./(tp);
            wn= (wd)./sqrt(1-zetha^2);
            ts2= 4./(zetha*wn); 
            ts5= 3./(zetha*wn); 
            varpor= 0.95*vfinal;
            rest= var-varpor;
            [mini,I]= min(abs(rest));
            tr= t(I); 


            G_red0 = tf(G_red.Numerator,G_red.Denominator)
            Kp = dcgain(G_red0)
            error_escalon = 1/(1+Kp)
            
            num1 = cell2mat(G_red.Numerator)
            G_red1 = tf(conv(num1,[1 0]),G_red.Denominator)
            Kv = dcgain(G_red1)
            error_rampa = 1/Kv

            G_red2 = tf(conv(num1,[1 0 0]),G_red.Denominator)
            Ka = dcgain(G_red2)
            error_parabola = 1/Ka

           
            app.zetha.Value = evalc("zetha");
            app.tr.Value = evalc("tr");
            app.tp.Value = evalc("tp");
            app.mp.Value = evalc("Mp");
            app.ts2.Value = evalc("ts2");
            app.ts5.Value = evalc("ts5");

            app.e_escalon.Value = evalc("error_escalon");
            app.e_rampa.Value = evalc("error_rampa");
            app.e_parabola.Value = evalc("error_parabola");

        end

        % Value changed function: ControladorDropDown
        function ControladorDropDownValueChanged(app, event)
            valueControlador = app.ControladorDropDown.Value;
            
            if valueControlador == "Proporcional"
                assignin('base','Constante2',1)
                app.DLabel.Visible = 'off';
                app.ILabel.Visible = 'off';
                app.Controlador_2.Visible = 'off';
                app.Controlador_3.Visible = 'off';

            elseif valueControlador == "Integral"
                assignin('base','Constante2',2)
                app.DLabel.Visible = 'off';
                app.ILabel.Visible = 'on';
                app.Controlador_2.Visible = 'on';
                app.Controlador_3.Visible = 'off';

            elseif valueControlador == "Derivativo"
                assignin('base','Constante2',3)
                app.DLabel.Visible = 'on';
                app.ILabel.Visible = 'off';
                app.Controlador_2.Visible = 'off';
                app.Controlador_3.Visible = 'on';
                
            end


        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
            global G_red Salida Salida2 G_red2

            %{
            t = 0:0.001:50;
            raices = rlocus(Salida);
            %%assignin('base',raices)
            y12 = raices(:,1,1);
            %}
            

            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;


            [r,k] = rlocus(G_red);
            f = figure;
            rlocus(G_red);

            %axes_handle = axes(f)
            %grid(axes_handle, 'on')

            %p = getoptions(h);
            %f.Grid = 'on';
            %setoptions(f,p);

            %rl = findobj(f, 'Type', 'Axes')
            %copyobj(rl.Children, app.RaicesGrafica);
            %delete(f);
            %app.RaicesGrafica.XLim = [xmin xmax];


            if xmin == xmax
                rl = findobj(f, 'Type', 'Axes')
                copyobj(rl.Children, app.RaicesGrafica);
                delete(f);

            elseif xmin > xmax
                rl = findobj(f, 'Type', 'Axes')
                copyobj(rl.Children, app.RaicesGrafica);
                delete(f);
                
            else
                rl = findobj(f, 'Type', 'Axes')
                copyobj(rl.Children, app.RaicesGrafica);
                delete(f);
                app.RaicesGrafica.XLim = [xmin xmax];
            end

            %app.RaicesGrafica.Grid
            %hold(app.RaicesGrafica,"off")


            [num_1 den_1] = tfdata(G_red2, 'v');

            [Routh, Estabilidad] = estrouth(num_1,den_1,1);

            app.ValoresK.Text = evalc('Estabilidad');


        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.RaicesGrafica);
        end

        % Button pushed function: PruebaButton
        function PruebaButtonPushed(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Region = uiaxes(app.UIFigure);
            title(app.Region, 'Title')
            xlabel(app.Region, 'X')
            ylabel(app.Region, 'Y')
            zlabel(app.Region, 'Z')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.FunciondetransferenciaTab = uitab(app.TabGroup2);
            app.FunciondetransferenciaTab.Title = 'Funcion de transferencia';
            app.VariablesdeestadoButtonGroup = uibuttongroup(app.FunciondetransferenciaTab);
            app.VariablesdeestadoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VariablesdeestadoButtonGroupSelectionChanged, true);
            app.VariablesdeestadoButtonGroup.Title = 'Variables de estado';
            app.MatrizAButton = uiradiobutton(app.VariablesdeestadoButtonGroup);
            app.MatrizAButton.Text = 'Matriz A';
            app.MatrizAButton.Value = true;
            app.MatrizBButton = uiradiobutton(app.VariablesdeestadoButtonGroup);
            app.MatrizBButton.Text = 'Matriz B';
            app.MatrizCButton = uiradiobutton(app.VariablesdeestadoButtonGroup);
            app.MatrizCButton.Text = 'Matriz C';
            app.MatrizDButton = uiradiobutton(app.VariablesdeestadoButtonGroup);
            app.MatrizDButton.Text = 'Matriz D';
            app.FuncindeTransferenciaLabel = uilabel(app.FunciondetransferenciaTab);
            app.FuncindeTransferenciaLabel.Text = 'Función de Transferencia:';
            app.Salida_funcion = uilabel(app.FunciondetransferenciaTab);
            app.Salida_funcion.Text = '';
            app.Matriz = uilabel(app.FunciondetransferenciaTab);
            app.Matriz.Text = '';
            app.Planta_funcion = uilabel(app.FunciondetransferenciaTab);
            app.Planta_funcion.Text = 'Planta';
            app.Actuador_funcion = uilabel(app.FunciondetransferenciaTab);
            app.Actuador_funcion.Text = 'Actuador';
            app.Sensor_funcion = uilabel(app.FunciondetransferenciaTab);
            app.Sensor_funcion.Text = 'Sensor';
            app.CalcularButton = uibutton(app.FunciondetransferenciaTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.FuncionreducidayparmetrosTab = uitab(app.TabGroup2);
            app.FuncionreducidayparmetrosTab.Title = 'Funcion reducida y parámetros';
            app.TFReducida = uilabel(app.FuncionreducidayparmetrosTab);
            app.TFReducida.Text = '';
            app.ReducirButton = uibutton(app.FuncionreducidayparmetrosTab, 'push');
            app.ReducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReducirButtonPushed, true);
            app.ReducirButton.Text = 'Reducir';
            app.CalcularButton_2 = uibutton(app.FuncionreducidayparmetrosTab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.ErrordeposicionLabel = uilabel(app.FuncionreducidayparmetrosTab);
            app.ErrordeposicionLabel.Text = 'Error de posicion';
            app.e_escalon = uieditfield(app.FuncionreducidayparmetrosTab, 'text');
            app.ErrordevelocidadLabel = uilabel(app.FuncionreducidayparmetrosTab);
            app.ErrordevelocidadLabel.Text = 'Error de velocidad';
            app.e_rampa = uieditfield(app.FuncionreducidayparmetrosTab, 'text');
            app.ErrordeaceleracionLabel = uilabel(app.FuncionreducidayparmetrosTab);
            app.ErrordeaceleracionLabel.Text = 'Error de aceleracion';
            app.e_parabola = uieditfield(app.FuncionreducidayparmetrosTab, 'text');
            app.zethaEditFieldLabel = uilabel(app.FuncionreducidayparmetrosTab);
            app.zethaEditFieldLabel.Text = 'zetha';
            app.zetha = uieditfield(app.FuncionreducidayparmetrosTab, 'text');
            app.SobreelongacinEditFieldLabel = uilabel(app.FuncionreducidayparmetrosTab);
            app.SobreelongacinEditFieldLabel.Text = 'Sobreelongación';
            app.mp = uieditfield(app.FuncionreducidayparmetrosTab, 'text');
            app.TiempopicoEditFieldLabel = uilabel(app.FuncionreducidayparmetrosTab);
            app.TiempopicoEditFieldLabel.Text = 'Tiempo pico';
            app.tp = uieditfield(app.FuncionreducidayparmetrosTab, 'text');
            app.TiempodesubidaEditFieldLabel = uilabel(app.FuncionreducidayparmetrosTab);
            app.TiempodesubidaEditFieldLabel.Text = 'Tiempo de subida';
            app.tr = uieditfield(app.FuncionreducidayparmetrosTab, 'text');
            app.Testablecimiento2EditFieldLabel = uilabel(app.FuncionreducidayparmetrosTab);
            app.Testablecimiento2EditFieldLabel.Text = 'T establecimiento (2%)';
            app.ts2 = uieditfield(app.FuncionreducidayparmetrosTab, 'text');
            app.Testablecimiento5EditFieldLabel = uilabel(app.FuncionreducidayparmetrosTab);
            app.Testablecimiento5EditFieldLabel.Text = 'T establecimiento (5%)';
            app.ts5 = uieditfield(app.FuncionreducidayparmetrosTab, 'text');
            app.EstabilidadTab = uitab(app.TabGroup2);
            app.EstabilidadTab.Title = 'Estabilidad';
            app.RaicesGrafica = uiaxes(app.EstabilidadTab);
            title(app.RaicesGrafica, 'Lugar de las raices')
            xlabel(app.RaicesGrafica, 'σ')
            ylabel(app.RaicesGrafica, 'ωj')
            zlabel(app.RaicesGrafica, 'Z')
            app.CalcularButton_3 = uibutton(app.EstabilidadTab, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.EstabilidadTab, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.LmiteejexminEditFieldLabel = uilabel(app.EstabilidadTab);
            app.LmiteejexminEditFieldLabel.Text = 'Límite eje x (min)';
            app.xminEditField = uieditfield(app.EstabilidadTab, 'numeric');
            app.LmiteejexmaxEditFieldLabel = uilabel(app.EstabilidadTab);
            app.LmiteejexmaxEditFieldLabel.Text = 'Límite eje x (max)';
            app.xmaxEditField = uieditfield(app.EstabilidadTab, 'numeric');
            app.ValoresK = uilabel(app.EstabilidadTab);
            app.ValoresK.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FuncindeTransferenciaoriginalTab = uitab(app.TabGroup);
            app.FuncindeTransferenciaoriginalTab.Title = 'Función de Transferencia original';
            app.Grafica1 = uiaxes(app.FuncindeTransferenciaoriginalTab);
            title(app.Grafica1, 'Respuesta')
            xlabel(app.Grafica1, 'Tiempo')
            ylabel(app.Grafica1, 'Amplitud')
            zlabel(app.Grafica1, 'Z')
            app.FuncindeTransferenciareducidaTab = uitab(app.TabGroup);
            app.FuncindeTransferenciareducidaTab.Title = 'Función de Transferencia reducida';
            app.Grafica2 = uiaxes(app.FuncindeTransferenciareducidaTab);
            title(app.Grafica2, 'Respuesta')
            xlabel(app.Grafica2, 'Tiempo')
            ylabel(app.Grafica2, 'Amplitud')
            zlabel(app.Grafica2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Balanceo avion.jpg';
            app.ControlalabeodeunavinLabel = uilabel(app.UIFigure);
            app.ControlalabeodeunavinLabel.Text = 'Control alabeo de un avión';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Diagrama de bloques2.png';
            app.ControladorLabel = uilabel(app.UIFigure);
            app.ControladorLabel.Text = 'Controlador';
            app.ActuadorLabel = uilabel(app.UIFigure);
            app.ActuadorLabel.Text = 'Actuador';
            app.PerturbacionesLabel = uilabel(app.UIFigure);
            app.PerturbacionesLabel.Text = 'Perturbaciones';
            app.PlantaLabel = uilabel(app.UIFigure);
            app.PlantaLabel.Text = 'Planta';
            app.SensorLabel = uilabel(app.UIFigure);
            app.SensorLabel.Text = 'Sensor';
            app.Actuador_num = uieditfield(app.UIFigure, 'text');
            app.Actuador_dem = uieditfield(app.UIFigure, 'text');
            app.Planta_num = uieditfield(app.UIFigure, 'text');
            app.Planta_dem = uieditfield(app.UIFigure, 'text');
            app.Sensor_dem = uieditfield(app.UIFigure, 'text');
            app.Sensor_num = uieditfield(app.UIFigure, 'text');
            app.Controlador = uieditfield(app.UIFigure, 'text');
            app.ConsignaDropDownLabel = uilabel(app.UIFigure);
            app.ConsignaDropDownLabel.Text = 'Consigna';
            app.ConsignaDropDown = uidropdown(app.UIFigure);
            app.ConsignaDropDown.Items = {'Impulso', 'Escalón', 'Rampa', 'Parábola'};
            app.ConsignaDropDown.ValueChangedFcn = createCallbackFcn(app, @ConsignaDropDownValueChanged, true);
            app.ConsignaDropDown.Value = 'Impulso';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.Text = 'Múltiples Respuestas';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.ControladorDropDownLabel = uilabel(app.UIFigure);
            app.ControladorDropDownLabel.Text = 'Controlador';
            app.ControladorDropDown = uidropdown(app.UIFigure);
            app.ControladorDropDown.Items = {'Proporcional', 'Integral', 'Derivativo'};
            app.ControladorDropDown.ValueChangedFcn = createCallbackFcn(app, @ControladorDropDownValueChanged, true);
            app.ControladorDropDown.Value = 'Proporcional';
            app.Controlador_2 = uieditfield(app.UIFigure, 'text');
            app.Controlador_2.Visible = 'off';
            app.Controlador_3 = uieditfield(app.UIFigure, 'text');
            app.Controlador_3.Visible = 'off';
            app.PruebaButton = uibutton(app.UIFigure, 'push');
            app.PruebaButton.ButtonPushedFcn = createCallbackFcn(app, @PruebaButtonPushed, true);
            app.PruebaButton.Text = 'Prueba';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
            app.ILabel = uilabel(app.UIFigure);
            app.ILabel.Visible = 'off';
            app.ILabel.Text = 'I';
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Visible = 'off';
            app.DLabel.Text = 'D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoIntegrador3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end