classdef P2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AproximacinPanel        matlab.ui.container.Panel
        RealPanel               matlab.ui.container.Panel
        GraficarRealButton      matlab.ui.control.Button
        FuncindetranferenciaaproximadaLabel  matlab.ui.control.Label
        AproximacinButton       matlab.ui.control.Button
        ArchivomagnitudLabel    matlab.ui.control.Label
        CargarButton_3          matlab.ui.control.Button
        CargarButton_2          matlab.ui.control.Button
        ArchivofrecuenciaLabel  matlab.ui.control.Label
        ArchivofaseLabel        matlab.ui.control.Label
        FuncindetransferenciaapartirdelBodeLabel  matlab.ui.control.Label
        CargarButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            global file_mag

            % Cargar archivos
            [file_mag,path] = uigetfile('.mat','Select a File')
            if ~contains(app.ArchivomagnitudLabel.Text,file_mag) % Verificar si ya se cargo
                load(fullfile(path,file_mag)) % Cargar el archivo
                app.ArchivomagnitudLabel.Text = ['Archivo magnitud:' newline file_mag]
                app.ArchivomagnitudLabel.VerticalAlignment = 'top'
            end
        end

        % Button pushed function: CargarButton_2
        function CargarButton_2Pushed(app, event)
            global file_fase
            
            % Cargar archivos
            [file_fase,path] = uigetfile('.mat','Select a File')
            if ~contains(app.ArchivofaseLabel.Text,file_fase) % Verificar si ya se cargo
                load(fullfile(path,file_fase)) % Cargar el archivo
                app.ArchivofaseLabel.Text = ['Archivo fase:' newline file_fase]
                app.ArchivofaseLabel.VerticalAlignment = 'top'
            end
        end

        % Button pushed function: CargarButton_3
        function CargarButton_3Pushed(app, event)
            global file_frecuencia
            
            % Cargar archivos
            [file_frecuencia,path] = uigetfile('.mat','Select a File')
            if ~contains(app.ArchivofrecuenciaLabel.Text,file_frecuencia) % Verificar si ya se cargo
                load(fullfile(path,file_frecuencia)) % Cargar el archivo
                app.ArchivofrecuenciaLabel.Text = ['Archivo frecuencia:' newline file_frecuencia]
                app.ArchivofrecuenciaLabel.VerticalAlignment = 'top'
            end
        end

        % Button pushed function: GraficarRealButton
        function GraficarRealButtonPushed(app, event)
            global file_mag
            global file_fase
            global file_frecuencia
            
            % Cargar variables
            info = load(file_mag);
            variables = fieldnames(info);
            Mag = info.(variables{1});

            info = load(file_fase);
            variables = fieldnames(info);
            Phase = info.(variables{1});

            info = load(file_frecuencia);
            variables = fieldnames(info);
            W = info.(variables{1});

            % Disminuir su dimensión
            Mag = squeeze(Mag);
            Phase = squeeze(Phase);
            W = squeeze(W);

            % Escribirlo en forma compleja
            H = Mag.*( cos(Phase.*pi/180) + 1i*sin(Phase.*pi/180) );
            
            % Crear axis
            app.RealPanel.AutoResizeChildren = 'off';
            ax1 = subplot(2,1,1,'Parent',app.RealPanel);
            ax2 = subplot(2,1,2,'Parent',app.RealPanel);

            % Graficar
            semilogx(ax1,W,20*log10(Mag))
            semilogx(ax2,W,Phase)
        end

        % Button pushed function: AproximacinButton
        function AproximacinButtonPushed(app, event)
            global file_mag
            global file_fase
            global file_frecuencia
            
            % Cargar variables
            info = load(file_mag);
            variables = fieldnames(info);
            Mag = info.(variables{1});

            info = load(file_fase);
            variables = fieldnames(info);
            Phase = info.(variables{1});

            info = load(file_frecuencia);
            variables = fieldnames(info);
            W = info.(variables{1});
            
            % Disminuir su dimensión
            Mag = squeeze(Mag);
            Phase = squeeze(Phase);
            W = squeeze(W);

            % Escribirlo en forma compleja
            H = Mag.*( cos(Phase.*pi/180) + 1i*sin(Phase.*pi/180) );
            Info_H = idfrd(H,W,0.1);
            
            % Aproximar función de transferencia a una de grado 2
            sys2 = tfest(Info_H,2);
            sys2 = tf(sys2);

            % Aproximar función de transferencia a una de grado 3
            sys3 = tfest(Info_H,3);
            sys3 = tf(sys3);

            % Obtener las variables para aproximación 2
            [Mag_aprox2 Phase_aprox2 W_aprox2] = bode(sys2);
            Mag_aprox2 = squeeze(Mag_aprox2);
            Phase_aprox2 = squeeze(Phase_aprox2);
            W_aprox2 = squeeze(W_aprox2);

            % Obtener las variables para aproximación 3
            [Mag_aprox3 Phase_aprox3 W_aprox3] = bode(sys3);
            Mag_aprox3 = squeeze(Mag_aprox3);
            Phase_aprox3 = squeeze(Phase_aprox3);
            W_aprox3 = squeeze(W_aprox3);

            % Verificar cual aproximación es mejor
            if abs(Phase(end) - Phase_aprox2(end)) < abs(Phase(end) - Phase_aprox3(end))
                Mag_aprox = Mag_aprox2;
                Phase_aprox = Phase_aprox2;
                W_aprox = W_aprox2;
                sys = sys2;
            else
                Mag_aprox = Mag_aprox3;
                Phase_aprox = Phase_aprox3;
                W_aprox = W_aprox3;
                sys = sys3;
            end

            % Crear axis
            app.AproximacinPanel.AutoResizeChildren = 'off';
            ax1_aprox = subplot(2,1,1,'Parent',app.AproximacinPanel);
            ax2_aprox = subplot(2,1,2,'Parent',app.AproximacinPanel);
            
            % Aproximar
            semilogx(ax1_aprox,W_aprox,20*log10(Mag_aprox))
            semilogx(ax2_aprox,W_aprox,Phase_aprox)

            % Mostrar la función de tranferencia
            fun_aprox = evalc('sys');
            app.FuncindetranferenciaaproximadaLabel.Text = ['Función de tranferencia aproximada:' newline fun_aprox(8:end-37)];
            app.FuncindetranferenciaaproximadaLabel.HorizontalAlignment = 'center';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.FuncindetransferenciaapartirdelBodeLabel = uilabel(app.UIFigure);
            app.FuncindetransferenciaapartirdelBodeLabel.Text = 'Función de transferencia apartir del Bode';
            app.ArchivofaseLabel = uilabel(app.UIFigure);
            app.ArchivofaseLabel.Text = 'Archivo fase:';
            app.ArchivofrecuenciaLabel = uilabel(app.UIFigure);
            app.ArchivofrecuenciaLabel.Text = 'Archivo frecuencia:';
            app.CargarButton_2 = uibutton(app.UIFigure, 'push');
            app.CargarButton_2.ButtonPushedFcn = createCallbackFcn(app, @CargarButton_2Pushed, true);
            app.CargarButton_2.Text = 'Cargar';
            app.CargarButton_3 = uibutton(app.UIFigure, 'push');
            app.CargarButton_3.ButtonPushedFcn = createCallbackFcn(app, @CargarButton_3Pushed, true);
            app.CargarButton_3.Text = 'Cargar';
            app.ArchivomagnitudLabel = uilabel(app.UIFigure);
            app.ArchivomagnitudLabel.Text = 'Archivo magnitud:';
            app.AproximacinButton = uibutton(app.UIFigure, 'push');
            app.AproximacinButton.ButtonPushedFcn = createCallbackFcn(app, @AproximacinButtonPushed, true);
            app.AproximacinButton.Text = 'Aproximación';
            app.FuncindetranferenciaaproximadaLabel = uilabel(app.UIFigure);
            app.FuncindetranferenciaaproximadaLabel.Text = 'Función de tranferencia aproximada:';
            app.GraficarRealButton = uibutton(app.UIFigure, 'push');
            app.GraficarRealButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarRealButtonPushed, true);
            app.GraficarRealButton.Text = 'Graficar Real';
            app.RealPanel = uipanel(app.UIFigure);
            app.RealPanel.Title = 'Real';
            app.AproximacinPanel = uipanel(app.UIFigure);
            app.AproximacinPanel.Title = 'Aproximación';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end